classdef CellViewer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        axes1                 matlab.ui.control.UIAxes
        uipanel1              matlab.ui.container.Panel
        txt_SmallObjSize      matlab.ui.control.EditField
        txt_SpurLength        matlab.ui.control.EditField
        text5                 matlab.ui.control.Label
        text6                 matlab.ui.control.Label
        txt_LocalThresh       matlab.ui.control.EditField
        text4                 matlab.ui.control.Label
        txt_Border            matlab.ui.control.EditField
        text6_2               matlab.ui.control.Label
        ModelDropDownLabel    matlab.ui.control.Label
        ModelDropDown         matlab.ui.control.DropDown
        UpdateButton          matlab.ui.control.Button
        txt_Resolution        matlab.ui.control.EditField
        text4_2               matlab.ui.control.Label
        txt_ImagePath         matlab.ui.control.EditField
        text3                 matlab.ui.control.Label
        btn_browse            matlab.ui.control.Button
        btn_Segment           matlab.ui.control.Button
        ShowHideMaskButton    matlab.ui.control.StateButton
        BatchProcessingPanel  matlab.ui.container.Panel
        txt_Image_Dir         matlab.ui.control.EditField
        text3_2               matlab.ui.control.Label
        btn_browse_dir        matlab.ui.control.Button
        btn_BatchSegment      matlab.ui.control.Button
        ProcessingLogPanel    matlab.ui.container.Panel
        logTextArea           matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        modelpath = pwd;
        exePath = fullfile(pwd,'test_single.exe');
        filepath = "";
        filename = "";
        fullpath = "";
        origim="";
        handCorrectim='';
        imgdir = "";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CellViewer_OpeningFcn(app)
            app.axes1.XAxis.TickLabels = {};
            app.axes1.YAxis.TickLabels = {};
            content = fileread('models.txt');
            data = textscan(content, '%s %s');
            app.ModelDropDown.Items=data{1};
            app.ModelDropDown.ItemsData=data{2};
            app.ModelDropDown.Value=data{2}{1};
        end

        % Button pushed function: btn_Segment
        function btn_Segment_Callback(app, event)
            
            app.fullpath = app.txt_ImagePath.Value;
            if isfile(app.fullpath)
                app.logTextArea.Value  = '';
                app.logTextArea.Value = 'Loading image.';drawnow;
                frames = java.awt.Frame.getFrames();
                frames(end).setAlwaysOnTop(1);
                app.origim = imread(app.fullpath);
                app.modelpath = app.modelpath;
                modelName = app.ModelDropDown.Value;
                app.exePath = app.exePath;
            
                thresh_win = app.txt_LocalThresh.Value;
                if (thresh_win == "")
                    thresh_win = '91';
                end
                model_res = app.txt_Resolution.Value;
                if (model_res == "")
                    model_res = '512';
                end
                
                exePath = ['"' app.exePath '"'];
                modelpath = ['"' app.modelpath '"'];
                
                app.logTextArea.Value = cat(1,app.logTextArea.Value,{'Running model...'});drawnow;
                commandComp = {exePath,'--test_dir_file "',app.fullpath,'" --model_dir',modelpath,'--res',model_res,'--name',modelName,'--thresh_window',thresh_win,'--force_rerun 1'};
                commandStr = strjoin(commandComp," ");
                system(commandStr);
                app.logTextArea.Value = cat(1,app.logTextArea.Value,{'Post processing mask.'});drawnow;
                
                borderWinTxt = str2num(app.txt_Border.Value);
                border_size = 0;
                if (~isempty(borderWinTxt))
                    border_size = borderWinTxt;
                end
                
                smallObjTrimTxt = str2num(app.txt_SmallObjSize.Value);
                smallObjTrim = 100;
                if (~isempty(smallObjTrimTxt))
                    smallObjTrim = smallObjTrimTxt;
                end
                spurTrimLengthTxt = str2num(app.txt_SpurLength.Value);
                spurTrimLength = 200;
                if (~isempty(spurTrimLengthTxt))
                    spurTrimLength = spurTrimLengthTxt;
                end
            
                [app.filepath,app.filename,fileext] = fileparts(app.fullpath);
                maskname = [app.filename '_predict_mask.png'];
                maskPath = fullfile(app.filepath,app.filename,maskname);
                handCorrectPath = fullfile(app.filepath,app.filename,'handCorrection.tif');
                handCorrectPathBkp = fullfile(app.filepath,app.filename,'handCorrection_bkp.tif');
                bwim = imread(maskPath);
                BW= bwmorph(bwim,'skel',Inf);
                BW = bwmorph(BW,'clean');
                BW = bwareaopen(BW,smallObjTrim);
                BW = bwmorph(BW,'spur',spurTrimLength);
                
                for i = 1:border_size
                    BW(i,:) = 0;
                    BW(end-i+1,:) = 0;
                    BW(:,i) = 0;
                    BW(:,end-i+1) = 0; 
                end
                
                app.logTextArea.Value = cat(1,app.logTextArea.Value,{'Writing log.'});drawnow;
                
                imwrite(BW,handCorrectPath,'Compression','none')
                imwrite(BW,handCorrectPathBkp,'Compression','none')
                app.handCorrectim = BW;
                
                %Write log file
                fileID = fopen(fullfile(app.filepath,app.filename,'handCorrection.log'),'w');
                fprintf(fileID,'Model Name:%s\n',modelName);
                fprintf(fileID,'Resolution:%s\n',model_res);
                fprintf(fileID,'Local Threshold Window:%d\n',str2num(thresh_win));
                fprintf(fileID,'Small Object Trim:%d\n',smallObjTrim);
                fprintf(fileID,'Spur Trim Length:%d\n',spurTrimLength);
                fprintf(fileID,'Border Crop Length:%d\n',border_size);
                fclose(fileID);
                
                overlay = imoverlay(app.origim,BW,'red');
                I = imshow(overlay,'Parent',app.axes1,...
                        'XData', [1 app.axes1.Position(3)], ...
                        'YData', [1 app.axes1.Position(4)]);
                app.axes1.XLim = [0 I.XData(2)];
                app.axes1.YLim = [0 I.YData(2)];
                app.logTextArea.Value = cat(1,app.logTextArea.Value,{'Completed.'});drawnow;
            end
        end

        % Button pushed function: btn_browse
        function btn_browse_Callback(app, event)

            app.figure1.Visible = 'off';
            [app.filename, app.filepath] = uigetfile({'*.tif';'*.png'},'Search Image to Process',app.filepath);
            
            if ~isequal(app.filename,0) && ~isequal(app.filepath,0)
                app.fullpath = [app.filepath app.filename];
                [app.filepath,app.filename,fileext] = fileparts(app.fullpath);
                handCorrectPath = fullfile(app.filepath,app.filename,'handCorrection.tif');
                
                if isfile(app.fullpath) && isfile(handCorrectPath)
                    app.handCorrectim = imread(handCorrectPath);
                    if size(app.handCorrectim,3)==3
                        app.handCorrectim = rgb2gray(app.handCorrectim);
                    end
                    app.origim = imread(app.fullpath);
                    overlay = imoverlay(app.origim,app.handCorrectim,'red');
                    I = imshow(overlay,'Parent',app.axes1,...
                        'XData', [1 app.axes1.Position(3)], ...
                        'YData', [1 app.axes1.Position(4)]);
                    app.axes1.XLim = [0 I.XData(2)];
                    app.axes1.YLim = [0 I.YData(2)];
                    app.txt_ImagePath.Value = app.fullpath;
                elseif isfile(app.fullpath)
                    app.txt_ImagePath.Value = app.fullpath;
                    app.origim = imread(app.fullpath);
                    I = imshow(app.origim,'Parent',app.axes1,...
                        'XData', [1 app.axes1.Position(3)], ...
                        'YData', [1 app.axes1.Position(4)]);
                    app.axes1.XLim = [0 I.XData(2)];
                    app.axes1.YLim = [0 I.YData(2)];
                end    
            else
                app.filepath = "";
            end
            figure(app.figure1)
        end

        % Value changed function: txt_SmallObjSize
        function txt_SmallObjSizeValueChanged(app, event)
            value = app.txt_SmallObjSize.Value;
            
        end

        % Value changed function: txt_SpurLength
        function txt_SpurLengthValueChanged(app, event)
            value = app.txt_SpurLength.Value;
            
        end

        % Value changed function: ShowHideMaskButton
        function ShowHideMaskButtonValueChanged(app, event)
            value = app.ShowHideMaskButton.Value;
            if ~isempty(app.handCorrectim) && value
                I = imshow(app.origim,'Parent',app.axes1,...
                        'XData', [1 app.axes1.Position(3)], ...
                        'YData', [1 app.axes1.Position(4)]);
                app.axes1.XLim = [0 I.XData(2)];
                app.axes1.YLim = [0 I.YData(2)];
            elseif ~isempty(app.handCorrectim)
                overlay = imoverlay(app.origim,app.handCorrectim,'red');
                I = imshow(overlay,'Parent',app.axes1,...
                        'XData', [1 app.axes1.Position(3)], ...
                        'YData', [1 app.axes1.Position(4)]);
                app.axes1.XLim = [0 I.XData(2)];
                app.axes1.YLim = [0 I.YData(2)];
            end
        end

        % Button pushed function: btn_BatchSegment
        function btn_BatchSegmentButtonPushed(app, event)
            
            thresh_win = app.txt_LocalThresh.Value;
            if (thresh_win == "")
                thresh_win = '91';
            end
            model_res = app.txt_Resolution.Value;
            if (model_res == "")
                model_res = '512';
            end
            borderWinTxt = str2num(app.txt_Border.Value);
            border_size = 0;
            if (~isempty(borderWinTxt))
                border_size = borderWinTxt;
            end
            
            smallObjTrimTxt = str2num(app.txt_SmallObjSize.Value);
            smallObjTrim = 100;
            if (~isempty(smallObjTrimTxt))
                smallObjTrim = smallObjTrimTxt;
            end
            
            spurTrimLengthTxt = str2num(app.txt_SpurLength.Value);
            spurTrimLength = 200;
            if (~isempty(spurTrimLengthTxt))
                spurTrimLength = spurTrimLengthTxt;
            end
            app.logTextArea.Value  = '';
            app.logTextArea.Value = 'Starting batch processing.';
            app.logTextArea.Value = cat(1,app.logTextArea.Value,{'Running model...'});drawnow;
            modelName = app.ModelDropDown.Value;
            exePath = ['"' app.exePath '"'];
            modelpath = ['"' app.modelpath '"'];
            commandComp = {exePath,'--test_dir_file "',app.imgdir,'" --model_dir',modelpath,'--res',model_res,'--name',modelName,'--thresh_window',thresh_win,'--force_rerun 1'};
            commandStr = strjoin(commandComp," ");
            system(commandStr);
            app.logTextArea.Value = cat(1,app.logTextArea.Value,{'Model processing complete.'});
            app.logTextArea.Value = cat(1,app.logTextArea.Value,{'Postprocessing masks.'});drawnow;
            files_png = dir(fullfile(app.imgdir,'*.png'));
            files_tif = dir(fullfile(app.imgdir,'*.tif'));
            file_names_png = {files_png.name};
            file_names_tif = {files_tif.name};
            file_names = [file_names_png file_names_tif];
            for i = 1:length(file_names)
                frames = java.awt.Frame.getFrames();
                frames(end).setAlwaysOnTop(1);
                batchfullpath = fullfile(app.imgdir,file_names(i));
                [batch_filepath,batch_filename,batch_fileext] = fileparts(batchfullpath{1});
                maskname = [batch_filename '_predict_mask.png'];
                maskPath = fullfile(batch_filepath,batch_filename,maskname);
                handCorrectPath = fullfile(batch_filepath,batch_filename,'handCorrection.tif');
                handCorrectPathBkp = fullfile(batch_filepath,batch_filename,'handCorrection_bkp.tif');
                bwim = imread(maskPath);
                BW = bwmorph(bwim,'skel',Inf);
                BW = bwmorph(BW,'clean');
                BW = bwareaopen(BW,smallObjTrim);
                BW = bwmorph(BW,'spur',spurTrimLength);
                
                for i = 1:border_size
                    BW(i,:) = 0;
                    BW(end-i+1,:) = 0;
                    BW(:,i) = 0;
                    BW(:,end-i+1) = 0; 
                end
                
                imwrite(BW,handCorrectPath,'Compression','none')
                imwrite(BW,handCorrectPathBkp,'Compression','none')
                
                %Write log file
                fileID = fopen(fullfile(batch_filepath,batch_filename,'handCorrection.log'),'w');
                fprintf(fileID,'Model Name:%s\n',modelName);
                fprintf(fileID,'Resolution:%s\n',model_res);
                fprintf(fileID,'Local Threshold Window:%d\n',str2num(thresh_win));
                fprintf(fileID,'Small Object Trim:%d\n',smallObjTrim);
                fprintf(fileID,'Spur Trim Length:%d\n',spurTrimLength);
                fprintf(fileID,'Border Crop Length:%d\n',border_size);
                fclose(fileID);
            end
            app.logTextArea.Value = cat(1,app.logTextArea.Value,{'Batch processing complete.'});drawnow;
        end

        % Button pushed function: btn_browse_dir
        function btn_browse_dirButtonPushed(app, event)
            app.figure1.Visible = 'off';
            app.imgdir = uigetdir(app.imgdir,'Select Image Folder to Process');
            if ~isequal(app.imgdir,0) 
                app.txt_Image_Dir.Value = app.imgdir;
            else
                app.txt_Image_Dir.Value = "";
                app.imgdir = "";
            end
            figure(app.figure1)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            app.logTextArea.Value  = '';
            app.logTextArea.Value = 'Update mask.';drawnow;
            
            smallObjTrimTxt = str2num(app.txt_SmallObjSize.Value);
            smallObjTrim = 100;
            if (~isempty(smallObjTrimTxt))
                smallObjTrim = smallObjTrimTxt;
            end
            spurTrimLengthTxt = str2num(app.txt_SpurLength.Value);
            spurTrimLength = 200;
            if (~isempty(spurTrimLengthTxt))
                spurTrimLength = spurTrimLengthTxt;
            end
            thresh_win = app.txt_LocalThresh.Value;
            if (thresh_win == "")
                thresh_win = '91';
            end
            borderWinTxt = str2num(app.txt_Border.Value);
            border_size = 0;
            if (~isempty(borderWinTxt))
                border_size = borderWinTxt;
            end
            
            [app.filepath,app.filename,fileext] = fileparts(app.fullpath);
            maskname = [app.filename '_predict_mask.png'];
            maskPath = fullfile(app.filepath,app.filename,maskname);
            handCorrectPath = fullfile(app.filepath,app.filename,'handCorrection.tif');
            handCorrectPathBkp = fullfile(app.filepath,app.filename,'handCorrection_bkp.tif');
            if isfile(maskPath)
                bwim = imread(maskPath);
                BW= bwmorph(bwim,'skel',Inf);
                BW = bwmorph(BW,'clean');
                BW = bwareaopen(BW,smallObjTrim);
                BW = bwmorph(BW,'spur',spurTrimLength);
                
                for i = 1:border_size
                    BW(i,:) = 0;
                    BW(end-i+1,:) = 0;
                    BW(:,i) = 0;
                    BW(:,end-i+1) = 0; 
                end

                imwrite(BW,handCorrectPath,'Compression','none')
                imwrite(BW,handCorrectPathBkp,'Compression','none')
                
                app.logTextArea.Value = cat(1,app.logTextArea.Value,{'Updating log.'});drawnow;
                %Write log file
                fileID = fopen(fullfile(app.filepath,app.filename,'handCorrection.log'),'w');
                fprintf(fileID,'Model Name:%s\n',app.ModelDropDown.Value);
                fprintf(fileID,'Resolution:%s\n',model_res);
                fprintf(fileID,'Local Threshold Window:%d\n',str2num(thresh_win));
                fprintf(fileID,'Small Object Trim:%d\n',smallObjTrim);
                fprintf(fileID,'Spur Trim Length:%d\n',spurTrimLength);
                fprintf(fileID,'Border Crop Length:%d\n',border_size);
                fclose(fileID);
                
                overlay = imoverlay(app.origim,BW,'red');
                I = imshow(overlay,'Parent',app.axes1,...
                        'XData', [1 app.axes1.Position(3)], ...
                        'YData', [1 app.axes1.Position(4)]);
                app.axes1.XLim = [0 I.XData(2)];
                app.axes1.YLim = [0 I.YData(2)];
            end
            app.logTextArea.Value = cat(1,app.logTextArea.Value,{'Update complete.'});drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Cell Viewer 1.9';
            app.axes1 = uiaxes(app.figure1);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Input Parameters';
            app.txt_SmallObjSize = uieditfield(app.uipanel1, 'text');
            app.txt_SmallObjSize.ValueChangedFcn = createCallbackFcn(app, @txt_SmallObjSizeValueChanged, true);
            app.txt_SmallObjSize.Value = '100';
            app.txt_SpurLength = uieditfield(app.uipanel1, 'text');
            app.txt_SpurLength.ValueChangedFcn = createCallbackFcn(app, @txt_SpurLengthValueChanged, true);
            app.txt_SpurLength.Value = '200';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'Small Object Size';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Text = 'Spur Length ';
            app.txt_LocalThresh = uieditfield(app.uipanel1, 'text');
            app.txt_LocalThresh.Value = '91';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Local Threshold Window';
            app.txt_Border = uieditfield(app.uipanel1, 'text');
            app.txt_Border.Value = '0';
            app.text6_2 = uilabel(app.uipanel1);
            app.text6_2.Text = 'Border Size';
            app.ModelDropDownLabel = uilabel(app.uipanel1);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.uipanel1);
            app.ModelDropDown.Items = {};
            app.ModelDropDown.Value = {};
            app.UpdateButton = uibutton(app.uipanel1, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.txt_Resolution = uieditfield(app.uipanel1, 'text');
            app.txt_Resolution.Value = '1024';
            app.text4_2 = uilabel(app.uipanel1);
            app.text4_2.Text = 'Resolution ';
            app.txt_ImagePath = uieditfield(app.figure1, 'text');
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Image File';
            app.btn_browse = uibutton(app.figure1, 'push');
            app.btn_browse.ButtonPushedFcn = createCallbackFcn(app, @btn_browse_Callback, true);
            app.btn_browse.Text = 'Browse Image';
            app.btn_Segment = uibutton(app.figure1, 'push');
            app.btn_Segment.ButtonPushedFcn = createCallbackFcn(app, @btn_Segment_Callback, true);
            app.btn_Segment.Text = 'Single Segment';
            app.ShowHideMaskButton = uibutton(app.figure1, 'state');
            app.ShowHideMaskButton.ValueChangedFcn = createCallbackFcn(app, @ShowHideMaskButtonValueChanged, true);
            app.ShowHideMaskButton.Text = {'Show/Hide Mask'; ''};
            app.BatchProcessingPanel = uipanel(app.figure1);
            app.BatchProcessingPanel.Title = 'Batch Processing Panel';
            app.txt_Image_Dir = uieditfield(app.BatchProcessingPanel, 'text');
            app.text3_2 = uilabel(app.BatchProcessingPanel);
            app.text3_2.Text = 'Image Directory';
            app.btn_browse_dir = uibutton(app.BatchProcessingPanel, 'push');
            app.btn_browse_dir.ButtonPushedFcn = createCallbackFcn(app, @btn_browse_dirButtonPushed, true);
            app.btn_browse_dir.Text = 'Browse Dir';
            app.btn_BatchSegment = uibutton(app.BatchProcessingPanel, 'push');
            app.btn_BatchSegment.ButtonPushedFcn = createCallbackFcn(app, @btn_BatchSegmentButtonPushed, true);
            app.btn_BatchSegment.Text = 'Batch Segment';
            app.ProcessingLogPanel = uipanel(app.figure1);
            app.ProcessingLogPanel.Title = 'Processing Log';
            app.logTextArea = uitextarea(app.ProcessingLogPanel);
            app.logTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellViewer_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @CellViewer_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end